classdef app_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        mainGrid                matlab.ui.container.GridLayout
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        Button3                 matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        SimulateButton          matlab.ui.control.Button
        ICbox                   matlab.ui.container.GridLayout
        endtimeEditField        matlab.ui.control.NumericEditField
        endtimeEditFieldLabel   matlab.ui.control.Label
        dtEditField             matlab.ui.control.NumericEditField
        dtEditFieldLabel        matlab.ui.control.Label
        LoadICButton            matlab.ui.control.Button
        InitialconditionsLabel  matlab.ui.control.Label
        dthetaic                matlab.ui.control.NumericEditField
        dotthetafraccircsLabel  matlab.ui.control.Label
        dxic                    matlab.ui.control.NumericEditField
        dotxfracmsLabel         matlab.ui.control.Label
        thetaic                 matlab.ui.control.NumericEditField
        thetacircLabel          matlab.ui.control.Label
        xic                     matlab.ui.control.NumericEditField
        xmLabel                 matlab.ui.control.Label
        paramsBox               matlab.ui.container.GridLayout
        ParametersLabel         matlab.ui.control.Label
        LoadDefaultsButton      matlab.ui.control.Button
        gammaEditField          matlab.ui.control.NumericEditField
        gammaEditFieldLabel     matlab.ui.control.Label
        bEditField              matlab.ui.control.NumericEditField
        bEditFieldLabel         matlab.ui.control.Label
        LpEditField             matlab.ui.control.NumericEditField
        L_pEditFieldLabel       matlab.ui.control.Label
        LcEditField             matlab.ui.control.NumericEditField
        L_cEditFieldLabel       matlab.ui.control.Label
        mpEditField             matlab.ui.control.NumericEditField
        m_pEditFieldLabel       matlab.ui.control.Label
        mcEditField             matlab.ui.control.NumericEditField
        m_cLabel                matlab.ui.control.Label
        MEditField              matlab.ui.control.NumericEditField
        MEditFieldLabel         matlab.ui.control.Label
        figAnimacja             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        basicParams = []; % params values taken from the user
        IC = [];% initial conditions
        extendedParams = [];
        simout; % simulink simulation output
    end
    
    methods (Access = private)
        function y = ustep(app, t, d)
            % t: czas start support'u
            % d : delay
            % dla x(t - d)
            N = length(t);
            y = zeros(1,N);
            for i = 1:N
                if t(i) >= d
                    y(i) = 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDefaultsButton
        function LoadDefaultsButtonPushed(app, event)
            % Changes default parameters (basicParams) which are later used
            % to calculate other parameters
            M = 0.05;
            mc = 0.1;
            mp = 0.1;
            Lc = 0.3;
            Lp = 0.15;
            g = 9.81;
            b = 0.001;
            gamma = 0.001;
            D = 0;
            alpha = 0;
            
            app.basicParams = [M, mc, mp, Lp, Lc, g, b, gamma, D, alpha];

            app.gammaEditField.Value = gamma;
            app.bEditField.Value     = b;
            app.LpEditField.Value    = Lp;
            app.LcEditField.Value    = Lc;
            app.mpEditField.Value    = mp;
            app.MEditField.Value     = M;
        end

        % Button pushed function: LoadICButton
        function LoadICButtonPushed(app, event)
            x_ic = app.xic;
            the_ic = app.thetaic;
            dx_ic = app.dxic;
            dthe_ic = app.dthetaic;
            app.IC = [x_ic, the_ic, dx_ic, dthe_ic];
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % This button triggers simulink simulation
            
            M       = app.basicParams(1);
            mc      = app.basicParams(2);
            mp      = app.basicParams(3);
            Lp      = app.basicParams(4);
            Lc      = app.basicParams(5);
            g       = app.basicParams(6);
            b       = app.basicParams(7);
            gamma   = app.basicParams(8);
            D       = app.basicParams(9);
            alpha   = app.basicParams(10);

            mr = mc + mp;
            Mt = mr + M;
            L  = (Lc*mc + Lp*mp) / mr;
            Jcm = (L^2)*mr + (Lc^2)*mc + 4/3 * (Lp^2)*mp;
            Jt  = Jcm + mr*L^2;

            app.extendedParams = [app.basicParams, mr, Mt, L, Jcm, Jt];

            dt = app.dtEditField.Value;
            end_time = app.endtimeEditField.Value;
            
            time = 0:dt:end_time;
            u1 = app.ustep(time, 3);
            u2 = app.ustep(time, 4);
            u3 = app.ustep(time, 4) * 0.5;
            u4 = app.ustep(time, 10) * 0.5;
            u5 = app.ustep(time, 10) * -2;
            u6 = app.ustep(time, 15) * -2;
            ref_sig = u1 - u2 + u3 - u4 + u5 - u6;
            ref_sig = ref_sig * 0;
            
            % Structure with time; input to simulink model
            U_sim.time = time;
            U_sim.signals.values(:, 1) = ref_sig;
            U_sim.signals.dimensions = 1;

            mdl = 'simulation_model';
            open_system(mdl);

            simInput = Simulink.SimulationInput(mdl);
            simInput.setExternalInput(U_sim)
            
            simInput.setVariable('params', app.extendedParams);
            simInput.setVariable('IC', app.IC);
            simInput = simInput.setVariable('end_time', end_time);
            app.simout = sim(simInput);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            states = app.simout.state_out;
            tout = app.simout.tout;
            
            plot(app.figAnimacja, tout, states(:, 1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.mainGrid = uigridlayout(app.UIFigure);
            app.figAnimacja = uiaxes(app.mainGrid);
            app.figAnimacja.XTick = [];
            app.figAnimacja.XTickLabel = '';
            app.figAnimacja.YTick = [];
            app.paramsBox = uigridlayout(app.mainGrid);
            app.MEditFieldLabel = uilabel(app.paramsBox);
            app.MEditFieldLabel.Interpreter = 'tex';
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.paramsBox, 'numeric');
            app.m_cLabel = uilabel(app.paramsBox);
            app.m_cLabel.Interpreter = 'tex';
            app.m_cLabel.Text = 'm_c';
            app.mcEditField = uieditfield(app.paramsBox, 'numeric');
            app.m_pEditFieldLabel = uilabel(app.paramsBox);
            app.m_pEditFieldLabel.Interpreter = 'tex';
            app.m_pEditFieldLabel.Text = 'm_p';
            app.mpEditField = uieditfield(app.paramsBox, 'numeric');
            app.L_cEditFieldLabel = uilabel(app.paramsBox);
            app.L_cEditFieldLabel.Interpreter = 'tex';
            app.L_cEditFieldLabel.Text = 'L_c';
            app.LcEditField = uieditfield(app.paramsBox, 'numeric');
            app.L_pEditFieldLabel = uilabel(app.paramsBox);
            app.L_pEditFieldLabel.Interpreter = 'tex';
            app.L_pEditFieldLabel.Text = 'L_p';
            app.LpEditField = uieditfield(app.paramsBox, 'numeric');
            app.bEditFieldLabel = uilabel(app.paramsBox);
            app.bEditFieldLabel.Interpreter = 'tex';
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.paramsBox, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.paramsBox);
            app.gammaEditFieldLabel.Interpreter = 'tex';
            app.gammaEditFieldLabel.Text = '\gamma';
            app.gammaEditField = uieditfield(app.paramsBox, 'numeric');
            app.LoadDefaultsButton = uibutton(app.paramsBox, 'push');
            app.LoadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultsButtonPushed, true);
            app.LoadDefaultsButton.Text = 'Load Defaults';
            app.ParametersLabel = uilabel(app.paramsBox);
            app.ParametersLabel.Text = 'Parameters';
            app.ICbox = uigridlayout(app.mainGrid);
            app.xmLabel = uilabel(app.ICbox);
            app.xmLabel.Interpreter = 'latex';
            app.xmLabel.Text = '$x\ [m]$';
            app.xic = uieditfield(app.ICbox, 'numeric');
            app.thetacircLabel = uilabel(app.ICbox);
            app.thetacircLabel.Interpreter = 'latex';
            app.thetacircLabel.Text = '$\theta\ [^\circ]$';
            app.thetaic = uieditfield(app.ICbox, 'numeric');
            app.dotxfracmsLabel = uilabel(app.ICbox);
            app.dotxfracmsLabel.Interpreter = 'latex';
            app.dotxfracmsLabel.Text = '$\dot{x}$ $\left[\frac{m}{s}\right]$';
            app.dxic = uieditfield(app.ICbox, 'numeric');
            app.dotthetafraccircsLabel = uilabel(app.ICbox);
            app.dotthetafraccircsLabel.Interpreter = 'latex';
            app.dotthetafraccircsLabel.Text = '$\dot{\theta}\ \left[\frac{^\circ}{s} \right] $';
            app.dthetaic = uieditfield(app.ICbox, 'numeric');
            app.InitialconditionsLabel = uilabel(app.ICbox);
            app.InitialconditionsLabel.Text = 'Initial conditions';
            app.LoadICButton = uibutton(app.ICbox, 'push');
            app.LoadICButton.ButtonPushedFcn = createCallbackFcn(app, @LoadICButtonPushed, true);
            app.LoadICButton.Text = 'Load IC';
            app.dtEditFieldLabel = uilabel(app.ICbox);
            app.dtEditFieldLabel.Interpreter = 'latex';
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.ICbox, 'numeric');
            app.dtEditField.Value = 0.001;
            app.endtimeEditFieldLabel = uilabel(app.ICbox);
            app.endtimeEditFieldLabel.Interpreter = 'latex';
            app.endtimeEditFieldLabel.Text = 'end time';
            app.endtimeEditField = uieditfield(app.ICbox, 'numeric');
            app.endtimeEditField.Value = 5;
            app.GridLayout = uigridlayout(app.mainGrid);
            app.SimulateButton = uibutton(app.GridLayout, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Button3 = uibutton(app.GridLayout, 'push');
            app.Button3.Text = 'Button3';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end